classdef EBBPrac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ChangePWMButton            matlab.ui.control.Button
        SamplingtoofastLabel       matlab.ui.control.Label
        BaudRateEditField          matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel     matlab.ui.control.Label
        COMPortEditField           matlab.ui.control.EditField
        COMPortEditFieldLabel      matlab.ui.control.Label
        StopButton                 matlab.ui.control.StateButton
        StartButton                matlab.ui.control.StateButton
        DisturbanceEditField       matlab.ui.control.NumericEditField
        DisturbanceEditFieldLabel  matlab.ui.control.Label
        SetpointEditField          matlab.ui.control.NumericEditField
        SetpointEditFieldLabel     matlab.ui.control.Label
        PWMPinDropDown             matlab.ui.control.DropDown
        PWMPinDropDownLabel        matlab.ui.control.Label
        DEditField                 matlab.ui.control.NumericEditField
        DEditFieldLabel            matlab.ui.control.Label
        IEditField                 matlab.ui.control.NumericEditField
        IEditFieldLabel            matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        DutyCycleEditField         matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel    matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        liveLine1
        liveLine2
        P
        I
        D
        setpoint
        disturbance
        duty_cycle
        PWM_flag
        capturing
        COM_port
        baud_rate
        window_size
        esp32
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            app.P = app.PEditField.Value;
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            app.I = app.IEditField.Value;
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            app.D = app.DEditField.Value;
        end

        % Value changed function: SetpointEditField
        function SetpointEditFieldValueChanged(app, event)
            app.setpoint = app.SetpointEditField.Value;
        end

        % Value changed function: DisturbanceEditField
        function DisturbanceEditFieldValueChanged(app, event)
            app.disturbance = app.DisturbanceEditField.Value;
        end

        % Value changed function: DutyCycleEditField
        function DutyCycleEditFieldValueChanged(app, event)
            app.duty_cycle = app.DutyCycleEditField.Value;
        end

        % Value changed function: PWMPinDropDown
        function PWMPinDropDownValueChanged(app, event)
            app.PWM_flag = app.PWMPinDropDown.Value;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.StartButton.Visible = "off";
            app.StopButton.Visible = "on";
            app.ChangePWMButton.Visible = "on";
            app.capturing = true;

            %Call all of the other functions to make sure their values are
            %captured.
            app.PEditFieldValueChanged();
            app.IEditFieldValueChanged();
            app.DEditFieldValueChanged();
            app.SetpointEditFieldValueChanged();
            app.DisturbanceEditFieldValueChanged();
            app.DutyCycleEditFieldValueChanged();
            app.PWMPinDropDownValueChanged();
            app.BaudRateEditFieldValueChanged();
            app.COMPortEditFieldValueChanged();

            if isempty(app.esp32)
                app.esp32 = serialport(app.COM_port, app.baud_rate);
                configureTerminator(app.esp32, "LF");
            end
            flush(app.esp32);

            pause(1);

            writeline(app.esp32, sprintf("%.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f", app.P, app.I, app.D, app.setpoint, app.disturbance, app.duty_cycle, app.PWM_flag, app.capturing));

            cla(app.UIAxes);

            app.liveLine1 = animatedline(app.UIAxes, 'Color', 'r');
            app.liveLine2 = animatedline(app.UIAxes, 'Color', 'b');

            app.window_size = 5000;

            while(app.capturing)
                if app.esp32.NumBytesAvailable > 0
                    rawData = readline(app.esp32);
                    data = str2double(split(rawData, ','));

                    if ~isnan(data(1))
                        addpoints(app.liveLine1, data(3), data(1));
                        addpoints(app.liveLine2, data(3), data(2));
                        xlim(app.UIAxes, [data(3) - app.window_size, data(3)])

                        if logical(data(end))
                            app.SamplingtoofastLabel.Visible = "on";
                        else
                            app.SamplingtoofastLabel.Visible = "off";
                        end
                    end

                    drawnow limitrate
                end
            end

            writeline(app.esp32, sprintf("%.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f", app.P, app.I, app.D, app.setpoint, app.disturbance, app.duty_cycle, app.PWM_flag, app.capturing));

            clear app.esp32;
        end

        % Value changed function: COMPortEditField
        function COMPortEditFieldValueChanged(app, event)
            app.COM_port = app.COMPortEditField.Value;
        end

        % Value changed function: BaudRateEditField
        function BaudRateEditFieldValueChanged(app, event)
            app.baud_rate = app.BaudRateEditField.Value;
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            app.capturing = false;
            app.StopButton.Visible = "off";
            app.ChangePWMButton.Visible = "off";
            app.StartButton.Visible = "on";

        end

        % Button pushed function: ChangePWMButton
        function ChangePWMButtonPushed(app, event)
            app.DutyCycleEditFieldValueChanged();
            writeline(app.esp32, sprintf("%.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f, %.2f", app.P, app.I, app.D, app.setpoint, app.disturbance, app.duty_cycle, app.PWM_flag, app.capturing));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {449, 449};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DutyCycleEditFieldLabel = uilabel(app.LeftPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DutyCycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutyCycleEditFieldValueChanged, true);
            app.PEditFieldLabel = uilabel(app.LeftPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.IEditFieldLabel = uilabel(app.LeftPanel);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.DEditFieldLabel = uilabel(app.LeftPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.PWMPinDropDownLabel = uilabel(app.LeftPanel);
            app.PWMPinDropDownLabel.Text = 'PWM Pin';
            app.PWMPinDropDown = uidropdown(app.LeftPanel);
            app.PWMPinDropDown.Items = {'PWM1', 'PWM2', 'Both'};
            app.PWMPinDropDown.ItemsData = [0 1 2];
            app.PWMPinDropDown.ValueChangedFcn = createCallbackFcn(app, @PWMPinDropDownValueChanged, true);
            app.PWMPinDropDown.Value = 0;
            app.SetpointEditFieldLabel = uilabel(app.LeftPanel);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.SetpointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SetpointEditField.ValueChangedFcn = createCallbackFcn(app, @SetpointEditFieldValueChanged, true);
            app.DisturbanceEditFieldLabel = uilabel(app.LeftPanel);
            app.DisturbanceEditFieldLabel.Text = 'Disturbance';
            app.DisturbanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DisturbanceEditField.ValueChangedFcn = createCallbackFcn(app, @DisturbanceEditFieldValueChanged, true);
            app.StartButton = uibutton(app.LeftPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.COMPortEditFieldLabel = uilabel(app.LeftPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.LeftPanel, 'text');
            app.COMPortEditField.ValueChangedFcn = createCallbackFcn(app, @COMPortEditFieldValueChanged, true);
            app.COMPortEditField.Value = '/dev/ttyUSB0';
            app.BaudRateEditFieldLabel = uilabel(app.LeftPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BaudRateEditField.ValueChangedFcn = createCallbackFcn(app, @BaudRateEditFieldValueChanged, true);
            app.BaudRateEditField.Value = 115200;
            app.SamplingtoofastLabel = uilabel(app.LeftPanel);
            app.SamplingtoofastLabel.Visible = 'off';
            app.SamplingtoofastLabel.Text = 'Sampling too fast!';
            app.ChangePWMButton = uibutton(app.LeftPanel, 'push');
            app.ChangePWMButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePWMButtonPushed, true);
            app.ChangePWMButton.Visible = 'off';
            app.ChangePWMButton.Text = 'Change PWM';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EBBPrac

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end